classdef mysortApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MysortUIFigure                 matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Panel2_5                       matlab.ui.container.Panel
        SAVEButton                     matlab.ui.control.Button
        TemplateMatchingButton         matlab.ui.control.Button
        MysortButton                   matlab.ui.control.Button
        MysortforTDTLabel              matlab.ui.control.Label
        Panel2_4                       matlab.ui.container.Panel
        TemplateEditField              matlab.ui.control.EditField
        TemplateEditFieldLabel         matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        DataEditField                  matlab.ui.control.EditField
        DataEditFieldLabel             matlab.ui.control.Label
        Panel2                         matlab.ui.container.Panel
        ThoptionDropDown               matlab.ui.control.DropDown
        ThoptionDropDownLabel          matlab.ui.control.Label
        KselectionmethodDropDown       matlab.ui.control.DropDown
        KselectionmethodDropDownLabel  matlab.ui.control.Label
        channelsEditField              matlab.ui.control.EditField
        channelsEditFieldLabel         matlab.ui.control.Label
        wavelengthsEditField           matlab.ui.control.EditField
        wavelengthsEditFieldLabel      matlab.ui.control.Label
        KEditField_2                   matlab.ui.control.EditField
        KEditField_2Label              matlab.ui.control.Label
        ThVEditField                   matlab.ui.control.EditField
        ThVEditFieldLabel              matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        PreviewTab                     matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        PCATab                         matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        WaveTab                        matlab.ui.container.Tab
        TabGroup_2                     matlab.ui.container.TabGroup
        Cluster1Tab                    matlab.ui.container.Tab
        UIAxes_4                       matlab.ui.control.UIAxes
        Cluster2Tab                    matlab.ui.container.Tab
        UIAxes_5                       matlab.ui.control.UIAxes
        Cluster3Tab                    matlab.ui.container.Tab
        UIAxes_6                       matlab.ui.control.UIAxes
        Cluster4Tab                    matlab.ui.container.Tab
        UIAxes_7                       matlab.ui.control.UIAxes
        TemplateTab                    matlab.ui.container.Tab
        UIAxes_9                       matlab.ui.control.UIAxes
        Tab                            matlab.ui.container.Tab
        UIAxes_8                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        Data
        SelectedGenders
        SelectedColors
        BinWidth
        Histogram = gobjects(0)
        displayedIndices
    end
    
    methods (Access = private)
        
        function NBins = numhistbins(app,data)
            % Utility function to compute the number of histogram bins 
            binwidth = app.BinWidth;
            range2plot =  floor(min(data)):binwidth:ceil(max(data));
            NBins = size(range2plot,2);
        end
        
        function annotateScatterPlot(app)
            % Update X and Y Labels
            app.UIAxes.XLabel.String = 'Weight';
            app.UIAxes.YLabel.String = app.BloodPressureSwitch.Value;
            % Dont show the histogram slider
            app.BinWidthSliderLabel.Visible = 'off';
            app.BinWidthSlider.Visible = 'off';
        end
        
        function annotateHistogram(app)
           
            % Update X and Y Labels
            app.UIAxes.XLabel.String = app.BloodPressureSwitch.Value;
            app.UIAxes.YLabel.String = '# of Patients';
            
            % Show histogram slider
            app.BinWidthSliderLabel.Visible = 'on';
            app.BinWidthSlider.Visible = 'on';
        end
        
        function filterData(app)
            % Utility function to filter the data according to the controls
            
            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            tempIndices = ones([size(app.Data,1),1]);
            
            % Append a column to tempIndices to indicate data that satisfies the smoker control
            if app.NoCheckBox.Value && ~app.YesCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 0];
            elseif app.YesCheckBox.Value && ~app.NoCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 1];
            elseif  ~app.YesCheckBox.Value && ~app.NoCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the gender control
            if app.MaleCheckBox.Value && ~app.FemaleCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Male"];
            elseif app.FemaleCheckBox.Value && ~app.MaleCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Female"];
            elseif  ~app.FemaleCheckBox.Value && ~app.MaleCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the location control
            if app.FilepathDropDown.Value ~= "All"
                tempIndices = [tempIndices, app.Data.Location == string(app.FilepathDropDown.Value)];
            end
            
            % Determine which data points satisfy all requirements
            app.displayedIndices = (sum(tempIndices,2)/size(tempIndices,2) == 1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.
            load('patients.mat','LastName','Gender','Smoker','Age','Height','Weight','Diastolic','Systolic','Location');
            
            % Store the data in a table and display it in one of the App's tabs.
            app.Data = table(LastName,Gender,Smoker,Age,Height,Weight,Diastolic,Systolic,Location);
            app.UITable.Data = app.Data;
            app.BinWidth = app.BinWidthSlider.Value;
            
            % Update the axes with the corresponding data.
            updateSelectedGenders(app)
            refreshplot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MysortUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {491, 491};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {286, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function SliderValueChanging(app, event)
            % Update the histogram as the slider value for bindwidth changes.
            app.BinWidth = event.Value;
            for ii=1:length(app.Histogram)
                app.Histogram(ii).NumBins = numhistbins(app,app.Histogram(ii).Data);
            end
        end

        % Callback function
        function refreshplot(app, event)
            Genders = app.SelectedGenders;
            Colors = app.SelectedColors;
            
            % Start with a fresh plot
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            app.Histogram = gobjects(0);
            
            % Select relevant segment of data
            xdata = app.Data.Weight;
            ydata = app.Data.(app.BloodPressureSwitch.Value);
            
            % Filter the data according to the controls
            filterData(app);
            
            % Create either a scatter plot or histogram, based on selection
            switch app.ButtonGroup.SelectedObject.Text
                
                case 'Scatter'
                    % Build a scatter plot for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        scatter(app.UIAxes,xdata((selectedpatients)),ydata(selectedpatients),Colors{ii});
                    end
                    annotateScatterPlot(app)
                    
                case 'Histogram'
                    % Build a histogram for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        NBins = numhistbins(app,ydata(selectedpatients));
                        h = histogram(app.UIAxes,ydata(selectedpatients),NBins,'BinLimits',[floor(min(ydata)) ceil(max(ydata))]);
                        h.EdgeColor = Colors{ii};
                        h.FaceColor = Colors{ii};
                        app.Histogram = [app.Histogram h];
                    end
                    annotateHistogram(app)
                    
            end
            
            % Update the table to show only the data that satisfies the controls
            app.UITable.Data = app.Data(app.displayedIndices,:);
            drawnow;
        end

        % Callback function
        function updateSelectedGenders(app, event)
            % List which genders and colors to use
            Genders = [];
            Colors = [];
            Smoker = [];
            
            if app.MaleCheckBox.Value
                Genders = "Male";
                Colors = "blue";
            end
            if app.FemaleCheckBox.Value
                Genders = [Genders "Female"];
                Colors = [Colors "red"];
            end
            if app.YesCheckBox.Value
                Smoker = "Yes";
            end
            if app.NoCheckBox.Value
                Smoker = [Smoker "No"];
            end
            
            if isempty(Genders) || isempty(Smoker)
                % Disable the switches and buttons if they were on
                app.BloodPressureSwitch.Enable = 'off';
                app.ScatterButton.Enable = 'off';
                app.HistogramButton.Enable = 'off';
                app.BinWidthSliderLabel.Enable = 'off';
                app.BinWidthSlider.Enable = 'off';
            else
                % Enable the switches and buttons if they were off
                app.BloodPressureSwitch.Enable = 'on';
                app.ScatterButton.Enable = 'on';
                app.HistogramButton.Enable = 'on';
                app.BinWidthSliderLabel.Enable = 'on';
                app.BinWidthSlider.Enable = 'on';
            end
            app.SelectedGenders = Genders;
            app.SelectedColors = Colors;
            
            refreshplot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MysortUIFigure = uifigure('Visible', 'off');
            app.MysortUIFigure.Name = 'Patients Display';
            app.MysortUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MysortUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2 = uipanel(app.LeftPanel);
            app.Panel2.Title = 'Parameter Settings';
            app.ThVEditFieldLabel = uilabel(app.Panel2);
            app.ThVEditFieldLabel.Text = 'Th (V)';
            app.ThVEditField = uieditfield(app.Panel2, 'text');
            app.KEditField_2Label = uilabel(app.Panel2);
            app.KEditField_2Label.Text = 'K';
            app.KEditField_2 = uieditfield(app.Panel2, 'text');
            app.wavelengthsEditFieldLabel = uilabel(app.Panel2);
            app.wavelengthsEditFieldLabel.Text = 'wave length (s)';
            app.wavelengthsEditField = uieditfield(app.Panel2, 'text');
            app.channelsEditFieldLabel = uilabel(app.Panel2);
            app.channelsEditFieldLabel.Text = 'channels';
            app.channelsEditField = uieditfield(app.Panel2, 'text');
            app.channelsEditField.Placeholder = 'All if left empty';
            app.KselectionmethodDropDownLabel = uilabel(app.Panel2);
            app.KselectionmethodDropDownLabel.Text = 'K selection method';
            app.KselectionmethodDropDown = uidropdown(app.Panel2);
            app.KselectionmethodDropDown.Items = {'gap', 'elbow', 'both', 'preview'};
            app.KselectionmethodDropDown.Value = 'preview';
            app.ThoptionDropDownLabel = uilabel(app.Panel2);
            app.ThoptionDropDownLabel.Text = 'Th option';
            app.ThoptionDropDown = uidropdown(app.Panel2);
            app.ThoptionDropDown.Items = {'reselect', 'origin', 'origin-reshape'};
            app.ThoptionDropDown.Value = 'reselect';
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'MAT data selection';
            app.DataEditFieldLabel = uilabel(app.Panel2_4);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.Panel2_4, 'text');
            app.Button = uibutton(app.Panel2_4, 'push');
            app.Button.Text = '...';
            app.Button_2 = uibutton(app.Panel2_4, 'push');
            app.Button_2.Text = '...';
            app.TemplateEditFieldLabel = uilabel(app.Panel2_4);
            app.TemplateEditFieldLabel.Text = 'Template';
            app.TemplateEditField = uieditfield(app.Panel2_4, 'text');
            app.MysortforTDTLabel = uilabel(app.LeftPanel);
            app.MysortforTDTLabel.Text = 'Mysort for TDT';
            app.Panel2_5 = uipanel(app.LeftPanel);
            app.Panel2_5.Title = 'Control Panel';
            app.MysortButton = uibutton(app.Panel2_5, 'push');
            app.MysortButton.Text = 'Mysort';
            app.TemplateMatchingButton = uibutton(app.Panel2_5, 'push');
            app.TemplateMatchingButton.Text = 'Template Matching';
            app.SAVEButton = uibutton(app.Panel2_5, 'push');
            app.SAVEButton.Text = 'SAVE';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.UIAxes = uiaxes(app.PreviewTab);
            title(app.UIAxes, 'Wave preview for th selection')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Voltage(V)')
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.UIAxes_2 = uiaxes(app.PCATab);
            title(app.UIAxes_2, 'PCA')
            xlabel(app.UIAxes_2, 'PC-1')
            ylabel(app.UIAxes_2, 'PC-2')
            zlabel(app.UIAxes_2, 'PC-3')
            app.WaveTab = uitab(app.TabGroup);
            app.WaveTab.Title = 'Wave';
            app.TabGroup_2 = uitabgroup(app.WaveTab);
            app.Cluster1Tab = uitab(app.TabGroup_2);
            app.Cluster1Tab.Title = 'Cluster 1';
            app.UIAxes_4 = uiaxes(app.Cluster1Tab);
            ylabel(app.UIAxes_4, 'Voltage(V)')
            app.Cluster2Tab = uitab(app.TabGroup_2);
            app.Cluster2Tab.Title = 'Cluster 2';
            app.UIAxes_5 = uiaxes(app.Cluster2Tab);
            ylabel(app.UIAxes_5, 'Voltage(V)')
            app.Cluster3Tab = uitab(app.TabGroup_2);
            app.Cluster3Tab.Title = 'Cluster 3';
            app.UIAxes_6 = uiaxes(app.Cluster3Tab);
            ylabel(app.UIAxes_6, 'Voltage(V)')
            app.Cluster4Tab = uitab(app.TabGroup_2);
            app.Cluster4Tab.Title = 'Cluster 4';
            app.UIAxes_7 = uiaxes(app.Cluster4Tab);
            ylabel(app.UIAxes_7, 'Voltage(V)')
            app.TemplateTab = uitab(app.TabGroup);
            app.TemplateTab.Title = 'Template';
            app.UIAxes_9 = uiaxes(app.TemplateTab);
            title(app.UIAxes_9, 'Waveform Template')
            ylabel(app.UIAxes_9, 'Voltage(V)')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes_8 = uiaxes(app.MysortUIFigure);
            title(app.UIAxes_8, 'Wave preview for th selection')
            xlabel(app.UIAxes_8, 'Time(s)')
            ylabel(app.UIAxes_8, 'Voltage(V)')
            % Show the figure after all components are created
            app.MysortUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mysortApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MysortUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MysortUIFigure)
        end
    end
end